classdef movementEntryMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        siteEditFieldLabel         matlab.ui.control.Label
        siteEditField              matlab.ui.control.NumericEditField
        depthEditFieldLabel        matlab.ui.control.Label
        depthEditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel    matlab.ui.control.Label
        amplitudeEditField         matlab.ui.control.NumericEditField
        timeEditFieldLabel         matlab.ui.control.Label
        timeEditField              matlab.ui.control.NumericEditField
        TabGroup                   matlab.ui.container.TabGroup
        headandfaceTab             matlab.ui.container.Tab
        vibrissaeLabel             matlab.ui.control.Label
        protractionCheckBox_6      matlab.ui.control.CheckBox
        retractionCheckBox_6       matlab.ui.control.CheckBox
        contralateralLabel_9       matlab.ui.control.Label
        ipsilateralLabel_9         matlab.ui.control.Label
        mystacialpadLabel          matlab.ui.control.Label
        protractionCheckBox_7      matlab.ui.control.CheckBox
        retractionCheckBox_7       matlab.ui.control.CheckBox
        movementCheckBox_3         matlab.ui.control.CheckBox
        movementCheckBox_4         matlab.ui.control.CheckBox
        eyeLabel                   matlab.ui.control.Label
        eyemovementCheckBox        matlab.ui.control.CheckBox
        eyelidmovementCheckBox     matlab.ui.control.CheckBox
        jawLabel                   matlab.ui.control.Label
        protractionCheckBox_8      matlab.ui.control.CheckBox
        retractionCheckBox_8       matlab.ui.control.CheckBox
        openingCheckBox            matlab.ui.control.CheckBox
        tongueLabel                matlab.ui.control.Label
        movementCheckBox           matlab.ui.control.CheckBox
        closingCheckBox            matlab.ui.control.CheckBox
        contralateralLabel_11      matlab.ui.control.Label
        ipsilateralLabel_11        matlab.ui.control.Label
        eyemovementCheckBox_2      matlab.ui.control.CheckBox
        eyelidmovementCheckBox_2   matlab.ui.control.CheckBox
        contralateralLabel_12      matlab.ui.control.Label
        ipsilateralLabel_12        matlab.ui.control.Label
        protractionCheckBox_9      matlab.ui.control.CheckBox
        retractionCheckBox_9       matlab.ui.control.CheckBox
        openingCheckBox_2          matlab.ui.control.CheckBox
        closingCheckBox_2          matlab.ui.control.CheckBox
        contralateralLabel_13      matlab.ui.control.Label
        ipsilateralLabel_13        matlab.ui.control.Label
        movementCheckBox_2         matlab.ui.control.CheckBox
        contralateralLabel_14      matlab.ui.control.Label
        ipsilateralLabel_14        matlab.ui.control.Label
        vibrationCheckBox          matlab.ui.control.CheckBox
        vibrationCheckBox_2        matlab.ui.control.CheckBox
        lipLabel                   matlab.ui.control.Label
        contralateralLabel_15      matlab.ui.control.Label
        ipsilateralLabel_15        matlab.ui.control.Label
        upperCheckBox              matlab.ui.control.CheckBox
        lowerCheckBox              matlab.ui.control.CheckBox
        upperCheckBox_2            matlab.ui.control.CheckBox
        lowerCheckBox_2            matlab.ui.control.CheckBox
        earLabel                   matlab.ui.control.Label
        movementCheckBox_5         matlab.ui.control.CheckBox
        movementCheckBox_6         matlab.ui.control.CheckBox
        contralateralLabel_16      matlab.ui.control.Label
        ipsilateralLabel_16        matlab.ui.control.Label
        neckLabel                  matlab.ui.control.Label
        movementCheckBox_7         matlab.ui.control.CheckBox
        movementCheckBox_8         matlab.ui.control.CheckBox
        contralateralLabel_17      matlab.ui.control.Label
        ipsilateralLabel_17        matlab.ui.control.Label
        forelimbTab                matlab.ui.container.Tab
        shoulderLabel              matlab.ui.control.Label
        elbowLabel                 matlab.ui.control.Label
        wristLabel                 matlab.ui.control.Label
        digitsLabel                matlab.ui.control.Label
        flexionCheckBox            matlab.ui.control.CheckBox
        extensionCheckBox          matlab.ui.control.CheckBox
        medialrotationCheckBox     matlab.ui.control.CheckBox
        lateralrotationCheckBox    matlab.ui.control.CheckBox
        flexionCheckBox_2          matlab.ui.control.CheckBox
        extensionCheckBox_2        matlab.ui.control.CheckBox
        pronationCheckBox          matlab.ui.control.CheckBox
        supinationCheckBox         matlab.ui.control.CheckBox
        flexionCheckBox_3          matlab.ui.control.CheckBox
        extensionCheckBox_3        matlab.ui.control.CheckBox
        flexionCheckBox_4          matlab.ui.control.CheckBox
        extensionCheckBox_4        matlab.ui.control.CheckBox
        protractionCheckBox        matlab.ui.control.CheckBox
        retractionCheckBox         matlab.ui.control.CheckBox
        abductionCheckBox          matlab.ui.control.CheckBox
        adductionCheckBox          matlab.ui.control.CheckBox
        abductionCheckBox_2        matlab.ui.control.CheckBox
        adductionCheckBox_2        matlab.ui.control.CheckBox
        contralateralLabel         matlab.ui.control.Label
        ipsilateralLabel           matlab.ui.control.Label
        flexionCheckBox_9          matlab.ui.control.CheckBox
        extensionCheckBox_9        matlab.ui.control.CheckBox
        medialrotationCheckBox_3   matlab.ui.control.CheckBox
        lateralrotationCheckBox_3  matlab.ui.control.CheckBox
        protractionCheckBox_3      matlab.ui.control.CheckBox
        retractionCheckBox_3       matlab.ui.control.CheckBox
        abductionCheckBox_5        matlab.ui.control.CheckBox
        adductionCheckBox_5        matlab.ui.control.CheckBox
        contralateralLabel_2       matlab.ui.control.Label
        ipsilateralLabel_2         matlab.ui.control.Label
        flexionCheckBox_10         matlab.ui.control.CheckBox
        extensionCheckBox_10       matlab.ui.control.CheckBox
        pronationCheckBox_2        matlab.ui.control.CheckBox
        supinationCheckBox_2       matlab.ui.control.CheckBox
        contralateralLabel_3       matlab.ui.control.Label
        ipsilateralLabel_3         matlab.ui.control.Label
        flexionCheckBox_11         matlab.ui.control.CheckBox
        extensionCheckBox_11       matlab.ui.control.CheckBox
        abductionCheckBox_6        matlab.ui.control.CheckBox
        adductionCheckBox_6        matlab.ui.control.CheckBox
        contralateralLabel_4       matlab.ui.control.Label
        ipsilateralLabel_4         matlab.ui.control.Label
        flexionCheckBox_12         matlab.ui.control.CheckBox
        extensionCheckBox_12       matlab.ui.control.CheckBox
        forearmLabel               matlab.ui.control.Label
        contralateralLabel_10      matlab.ui.control.Label
        ipsilateralLabel_10        matlab.ui.control.Label
        abductionCheckBox_11       matlab.ui.control.CheckBox
        adductionCheckBox_11       matlab.ui.control.CheckBox
        abductionCheckBox_12       matlab.ui.control.CheckBox
        adductionCheckBox_12       matlab.ui.control.CheckBox
        elevationCheckBox          matlab.ui.control.CheckBox
        depressionCheckBox         matlab.ui.control.CheckBox
        elevationCheckBox_2        matlab.ui.control.CheckBox
        depressionCheckBox_2       matlab.ui.control.CheckBox
        trunkTab                   matlab.ui.container.Tab
        clockwiserotationCheckBox  matlab.ui.control.CheckBox
        skinhairmovementCheckBox   matlab.ui.control.CheckBox
        flexionCheckBox_21         matlab.ui.control.CheckBox
        extensionCheckBox_21       matlab.ui.control.CheckBox
        lateralflexiontocontralateralsideCheckBox  matlab.ui.control.CheckBox
        lateralflexiontoipsilateralsideCheckBox  matlab.ui.control.CheckBox
        counterclockwiserotationCheckBox  matlab.ui.control.CheckBox
        hindlimbTab                matlab.ui.container.Tab
        hipLabel                   matlab.ui.control.Label
        kneeLabel                  matlab.ui.control.Label
        ankleLabel                 matlab.ui.control.Label
        toesLabel                  matlab.ui.control.Label
        flexionCheckBox_13         matlab.ui.control.CheckBox
        extensionCheckBox_13       matlab.ui.control.CheckBox
        medialrotationCheckBox_4   matlab.ui.control.CheckBox
        lateralrotationCheckBox_4  matlab.ui.control.CheckBox
        flexionCheckBox_14         matlab.ui.control.CheckBox
        extensionCheckBox_14       matlab.ui.control.CheckBox
        pronationCheckBox_3        matlab.ui.control.CheckBox
        supinationCheckBox_3       matlab.ui.control.CheckBox
        flexionCheckBox_15         matlab.ui.control.CheckBox
        extensionCheckBox_15       matlab.ui.control.CheckBox
        flexionCheckBox_16         matlab.ui.control.CheckBox
        extensionCheckBox_16       matlab.ui.control.CheckBox
        abductionCheckBox_7        matlab.ui.control.CheckBox
        adductionCheckBox_7        matlab.ui.control.CheckBox
        abductionCheckBox_8        matlab.ui.control.CheckBox
        adductionCheckBox_8        matlab.ui.control.CheckBox
        contralateralLabel_5       matlab.ui.control.Label
        ipsilateralLabel_5         matlab.ui.control.Label
        flexionCheckBox_17         matlab.ui.control.CheckBox
        extensionCheckBox_17       matlab.ui.control.CheckBox
        medialrotationCheckBox_5   matlab.ui.control.CheckBox
        lateralrotationCheckBox_5  matlab.ui.control.CheckBox
        abductionCheckBox_9        matlab.ui.control.CheckBox
        adductionCheckBox_9        matlab.ui.control.CheckBox
        contralateralLabel_6       matlab.ui.control.Label
        ipsilateralLabel_6         matlab.ui.control.Label
        flexionCheckBox_18         matlab.ui.control.CheckBox
        extensionCheckBox_18       matlab.ui.control.CheckBox
        pronationCheckBox_4        matlab.ui.control.CheckBox
        supinationCheckBox_4       matlab.ui.control.CheckBox
        contralateralLabel_7       matlab.ui.control.Label
        ipsilateralLabel_7         matlab.ui.control.Label
        flexionCheckBox_19         matlab.ui.control.CheckBox
        extensionCheckBox_19       matlab.ui.control.CheckBox
        abductionCheckBox_10       matlab.ui.control.CheckBox
        adductionCheckBox_10       matlab.ui.control.CheckBox
        contralateralLabel_8       matlab.ui.control.Label
        ipsilateralLabel_8         matlab.ui.control.Label
        flexionCheckBox_20         matlab.ui.control.CheckBox
        extensionCheckBox_20       matlab.ui.control.CheckBox
        inversionCheckBox          matlab.ui.control.CheckBox
        eversionCheckBox           matlab.ui.control.CheckBox
        inversionCheckBox_2        matlab.ui.control.CheckBox
        eversionCheckBox_2         matlab.ui.control.CheckBox
        tailTab                    matlab.ui.container.Tab
        yesmovementCheckBox        matlab.ui.control.CheckBox
        othersTab                  matlab.ui.container.Tab
        extraEditFieldLabel        matlab.ui.control.Label
        extraEditField             matlab.ui.control.EditField
        pleasetypeinanyextrainformationhereLabel  matlab.ui.control.Label
        doneButton                 matlab.ui.control.Button
        nextButton                 matlab.ui.control.Button
        nextsiteButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        dataTable = readtable("dataTableTemplate.xlsx") % the data table
        nthRow = 1; % the row on which the data is put
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: siteEditField
        function siteEditFieldValueChanged(app, event)
            value = app.siteEditField.Value;
            app.dataTable{app.nthRow,1} = value;
            %app.dataTable(app.nthRow, 2:13) = "";
        end

        % Value changed function: depthEditField
        function depthEditFieldValueChanged(app, event)
            value = app.depthEditField.Value;
            app.dataTable{app.nthRow,2} = value;
        end

        % Value changed function: amplitudeEditField
        function amplitudeEditFieldValueChanged(app, event)
            value = app.amplitudeEditField.Value;
            app.dataTable{app.nthRow,3} = value;
        end

        % Value changed function: timeEditField
        function timeEditFieldValueChanged(app, event)
            value = app.timeEditField.Value;
            app.dataTable{app.nthRow,4} = value;
        end

        % Value changed function: protractionCheckBox_6
        function protractionCheckBox_6ValueChanged(app, event)
            value = app.protractionCheckBox_6.Value;
            app.dataTable{app.nthRow,5} = value; 
        end

        % Value changed function: retractionCheckBox_6
        function retractionCheckBox_6ValueChanged(app, event)
            value = app.retractionCheckBox_6.Value;
            app.dataTable{app.nthRow,6} = value;
        end

        % Value changed function: vibrationCheckBox
        function vibrationCheckBoxValueChanged(app, event)
            value = app.vibrationCheckBox.Value;
            app.dataTable{app.nthRow,7} = value;
        end

        % Value changed function: protractionCheckBox_7
        function protractionCheckBox_7ValueChanged(app, event)
            value = app.protractionCheckBox_7.Value;
            app.dataTable{app.nthRow,8} = value;
        end

        % Value changed function: retractionCheckBox_7
        function retractionCheckBox_7ValueChanged(app, event)
            value = app.retractionCheckBox_7.Value;
            app.dataTable{app.nthRow,9} = value;
        end

        % Value changed function: vibrationCheckBox_2
        function vibrationCheckBox_2ValueChanged(app, event)
            value = app.vibrationCheckBox_2.Value;
            app.dataTable{app.nthRow,10} = value;
        end

        % Value changed function: eyemovementCheckBox
        function eyemovementCheckBoxValueChanged(app, event)
            value = app.eyemovementCheckBox.Value;
            app.dataTable{app.nthRow,11} = value;
        end

        % Value changed function: eyelidmovementCheckBox
        function eyelidmovementCheckBoxValueChanged(app, event)
            value = app.eyelidmovementCheckBox.Value;
            app.dataTable{app.nthRow,12} = value;
        end

        % Value changed function: eyemovementCheckBox_2
        function eyemovementCheckBox_2ValueChanged(app, event)
            value = app.eyemovementCheckBox_2.Value;
            app.dataTable{app.nthRow,13} = value;
        end

        % Value changed function: eyelidmovementCheckBox_2
        function eyelidmovementCheckBox_2ValueChanged(app, event)
            value = app.eyelidmovementCheckBox_2.Value;
            app.dataTable{app.nthRow,14} = value;
        end

        % Value changed function: protractionCheckBox_8
        function protractionCheckBox_8ValueChanged(app, event)
            value = app.protractionCheckBox_8.Value;
            app.dataTable{app.nthRow,15} = value;
        end

        % Value changed function: retractionCheckBox_8
        function retractionCheckBox_8ValueChanged(app, event)
            value = app.retractionCheckBox_8.Value;
            app.dataTable{app.nthRow,16} = value;
        end

        % Value changed function: openingCheckBox
        function openingCheckBoxValueChanged(app, event)
            value = app.openingCheckBox.Value;
            app.dataTable{app.nthRow,17} = value;
        end

        % Value changed function: closingCheckBox
        function closingCheckBoxValueChanged(app, event)
            value = app.closingCheckBox.Value;
            app.dataTable{app.nthRow,18} = value;
        end

        % Value changed function: protractionCheckBox_9
        function protractionCheckBox_9ValueChanged(app, event)
            value = app.protractionCheckBox_9.Value;
            app.dataTable{app.nthRow,19} = value;
        end

        % Value changed function: retractionCheckBox_9
        function retractionCheckBox_9ValueChanged(app, event)
            value = app.retractionCheckBox_9.Value;
            app.dataTable{app.nthRow,20} = value;
        end

        % Value changed function: openingCheckBox_2
        function openingCheckBox_2ValueChanged(app, event)
            value = app.openingCheckBox_2.Value;
            app.dataTable{app.nthRow,21} = value;
        end

        % Value changed function: closingCheckBox_2
        function closingCheckBox_2ValueChanged(app, event)
            value = app.closingCheckBox_2.Value;
            app.dataTable{app.nthRow,22} = value;
        end

        % Value changed function: movementCheckBox
        function movementCheckBoxValueChanged(app, event)
            value = app.movementCheckBox.Value;
            app.dataTable{app.nthRow,23} = value;
        end

        % Value changed function: movementCheckBox_2
        function movementCheckBox_2ValueChanged(app, event)
            value = app.movementCheckBox_2.Value;
            app.dataTable{app.nthRow,24} = value;
        end

        % Value changed function: upperCheckBox
        function upperCheckBoxValueChanged(app, event)
            value = app.upperCheckBox.Value;
            app.dataTable{app.nthRow,25} = value;
        end

        % Value changed function: lowerCheckBox
        function lowerCheckBoxValueChanged(app, event)
            value = app.lowerCheckBox.Value;
            app.dataTable{app.nthRow,26} = value;
        end

        % Value changed function: upperCheckBox_2
        function upperCheckBox_2ValueChanged(app, event)
            value = app.upperCheckBox_2.Value;
            app.dataTable{app.nthRow,27} = value;
        end

        % Value changed function: lowerCheckBox_2
        function lowerCheckBox_2ValueChanged(app, event)
            value = app.lowerCheckBox_2.Value;
            app.dataTable{app.nthRow,28} = value;
        end

        % Value changed function: movementCheckBox_4
        function movementCheckBox_4ValueChanged(app, event)
            value = app.movementCheckBox_4.Value;
            app.dataTable{app.nthRow,29} = value;
        end

        % Value changed function: movementCheckBox_3
        function movementCheckBox_3ValueChanged(app, event)
            value = app.movementCheckBox_3.Value;
            app.dataTable{app.nthRow,30} = value;
        end

        % Value changed function: movementCheckBox_6
        function movementCheckBox_6ValueChanged(app, event)
            value = app.movementCheckBox_6.Value;
            app.dataTable{app.nthRow,31} = value;
        end

        % Value changed function: movementCheckBox_5
        function movementCheckBox_5ValueChanged(app, event)
            value = app.movementCheckBox_5.Value;
            app.dataTable{app.nthRow,32} = value;
        end

        % Value changed function: movementCheckBox_8
        function movementCheckBox_8ValueChanged(app, event)
            value = app.movementCheckBox_8.Value;
            app.dataTable{app.nthRow,33} = value;
        end

        % Value changed function: movementCheckBox_7
        function movementCheckBox_7ValueChanged(app, event)
            value = app.movementCheckBox_7.Value;
            app.dataTable{app.nthRow,34} = value;
        end

        % Value changed function: flexionCheckBox
        function flexionCheckBoxValueChanged(app, event)
            value = app.flexionCheckBox.Value;
            app.dataTable{app.nthRow,35} = value;
        end

        % Value changed function: extensionCheckBox
        function extensionCheckBoxValueChanged(app, event)
            value = app.extensionCheckBox.Value;
            app.dataTable{app.nthRow,36} = value;
        end

        % Value changed function: medialrotationCheckBox
        function medialrotationCheckBoxValueChanged(app, event)
            value = app.medialrotationCheckBox.Value;
            app.dataTable{app.nthRow,37} = value;
        end

        % Value changed function: lateralrotationCheckBox
        function lateralrotationCheckBoxValueChanged(app, event)
            value = app.lateralrotationCheckBox.Value;
            app.dataTable{app.nthRow,38} = value;
        end

        % Value changed function: protractionCheckBox
        function protractionCheckBoxValueChanged(app, event)
            value = app.protractionCheckBox.Value;
            app.dataTable{app.nthRow,39} = value;
        end

        % Value changed function: retractionCheckBox
        function retractionCheckBoxValueChanged(app, event)
            value = app.retractionCheckBox.Value;
            app.dataTable{app.nthRow,40} = value;
        end

        % Value changed function: abductionCheckBox
        function abductionCheckBoxValueChanged(app, event)
            value = app.abductionCheckBox.Value;
            app.dataTable{app.nthRow,41} = value;
        end

        % Value changed function: adductionCheckBox
        function adductionCheckBoxValueChanged(app, event)
            value = app.adductionCheckBox.Value;
            app.dataTable{app.nthRow,42} = value;
        end

        % Value changed function: elevationCheckBox
        function elevationCheckBoxValueChanged(app, event)
            value = app.elevationCheckBox.Value;
            app.dataTable{app.nthRow,43} = value;
        end

        % Value changed function: depressionCheckBox
        function depressionCheckBoxValueChanged(app, event)
            value = app.depressionCheckBox.Value;
            app.dataTable{app.nthRow,44} = value;
        end

        % Value changed function: flexionCheckBox_9
        function flexionCheckBox_9ValueChanged(app, event)
            value = app.flexionCheckBox_9.Value;
            app.dataTable{app.nthRow,45} = value;
        end

        % Value changed function: extensionCheckBox_9
        function extensionCheckBox_9ValueChanged(app, event)
            value = app.extensionCheckBox_9.Value;
            app.dataTable{app.nthRow,46} = value;
        end

        % Value changed function: medialrotationCheckBox_3
        function medialrotationCheckBox_3ValueChanged(app, event)
            value = app.medialrotationCheckBox_3.Value;
            app.dataTable{app.nthRow,47} = value;
        end

        % Value changed function: lateralrotationCheckBox_3
        function lateralrotationCheckBox_3ValueChanged(app, event)
            value = app.lateralrotationCheckBox_3.Value;
            app.dataTable{app.nthRow,48} = value;
        end

        % Value changed function: protractionCheckBox_3
        function protractionCheckBox_3ValueChanged(app, event)
            value = app.protractionCheckBox_3.Value;
            app.dataTable{app.nthRow,49} = value;
        end

        % Value changed function: retractionCheckBox_3
        function retractionCheckBox_3ValueChanged(app, event)
            value = app.retractionCheckBox_3.Value;
            app.dataTable{app.nthRow,50} = value;
        end

        % Value changed function: abductionCheckBox_5
        function abductionCheckBox_5ValueChanged(app, event)
            value = app.abductionCheckBox_5.Value;
            app.dataTable{app.nthRow,51} = value;
        end

        % Value changed function: adductionCheckBox_5
        function adductionCheckBox_5ValueChanged(app, event)
            value = app.adductionCheckBox_5.Value;
            app.dataTable{app.nthRow,52} = value;
        end

        % Value changed function: elevationCheckBox_2
        function elevationCheckBox_2ValueChanged(app, event)
            value = app.elevationCheckBox_2.Value;
            app.dataTable{app.nthRow,53} = value;
        end

        % Value changed function: depressionCheckBox_2
        function depressionCheckBox_2ValueChanged(app, event)
            value = app.depressionCheckBox_2.Value;
            app.dataTable{app.nthRow,54} = value;
        end

        % Value changed function: flexionCheckBox_2
        function flexionCheckBox_2ValueChanged(app, event)
            value = app.flexionCheckBox_2.Value;
            app.dataTable{app.nthRow,55} = value;
        end

        % Value changed function: extensionCheckBox_2
        function extensionCheckBox_2ValueChanged(app, event)
            value = app.extensionCheckBox_2.Value;
            app.dataTable{app.nthRow,56} = value;
        end

        % Value changed function: flexionCheckBox_10
        function flexionCheckBox_10ValueChanged(app, event)
            value = app.flexionCheckBox_10.Value;
            app.dataTable{app.nthRow,57} = value;
        end

        % Value changed function: extensionCheckBox_10
        function extensionCheckBox_10ValueChanged(app, event)
            value = app.extensionCheckBox_10.Value;
            app.dataTable{app.nthRow,58} = value;
        end

        % Value changed function: pronationCheckBox
        function pronationCheckBoxValueChanged(app, event)
            value = app.pronationCheckBox.Value;
            app.dataTable{app.nthRow,59} = value;
        end

        % Value changed function: supinationCheckBox
        function supinationCheckBoxValueChanged(app, event)
            value = app.supinationCheckBox.Value;
            app.dataTable{app.nthRow,60} = value;
        end

        % Value changed function: pronationCheckBox_2
        function pronationCheckBox_2ValueChanged(app, event)
            value = app.pronationCheckBox_2.Value;
            app.dataTable{app.nthRow,61} = value;
        end

        % Value changed function: supinationCheckBox_2
        function supinationCheckBox_2ValueChanged(app, event)
            value = app.supinationCheckBox_2.Value;
            app.dataTable{app.nthRow,62} = value;
        end

        % Value changed function: flexionCheckBox_3
        function flexionCheckBox_3ValueChanged(app, event)
            value = app.flexionCheckBox_3.Value;
            app.dataTable{app.nthRow,63} = value;
        end

        % Value changed function: extensionCheckBox_3
        function extensionCheckBox_3ValueChanged(app, event)
            value = app.extensionCheckBox_3.Value;
            app.dataTable{app.nthRow,64} = value;
        end

        % Value changed function: abductionCheckBox_2
        function abductionCheckBox_2ValueChanged(app, event)
            value = app.abductionCheckBox_2.Value;
            app.dataTable{app.nthRow,65} = value;
        end

        % Value changed function: adductionCheckBox_2
        function adductionCheckBox_2ValueChanged(app, event)
            value = app.adductionCheckBox_2.Value;
            app.dataTable{app.nthRow,66} = value;
        end

        % Value changed function: flexionCheckBox_11
        function flexionCheckBox_11ValueChanged(app, event)
            value = app.flexionCheckBox_11.Value;
            app.dataTable{app.nthRow,67} = value;
        end

        % Value changed function: extensionCheckBox_11
        function extensionCheckBox_11ValueChanged(app, event)
            value = app.extensionCheckBox_11.Value;
            app.dataTable{app.nthRow,68} = value;
        end

        % Value changed function: abductionCheckBox_6
        function abductionCheckBox_6ValueChanged(app, event)
            value = app.abductionCheckBox_6.Value;
            app.dataTable{app.nthRow,69} = value;
        end

        % Value changed function: adductionCheckBox_6
        function adductionCheckBox_6ValueChanged(app, event)
            value = app.adductionCheckBox_6.Value;
            app.dataTable{app.nthRow,70} = value;
        end

        % Value changed function: flexionCheckBox_4
        function flexionCheckBox_4ValueChanged(app, event)
            value = app.flexionCheckBox_4.Value;
            app.dataTable{app.nthRow,71} = value;
        end

        % Value changed function: extensionCheckBox_4
        function extensionCheckBox_4ValueChanged(app, event)
            value = app.extensionCheckBox_4.Value;
            app.dataTable{app.nthRow,72} = value;
        end

        % Value changed function: abductionCheckBox_11
        function abductionCheckBox_11ValueChanged(app, event)
            value = app.abductionCheckBox_11.Value;
            app.dataTable{app.nthRow,73} = value;
        end

        % Value changed function: adductionCheckBox_11
        function adductionCheckBox_11ValueChanged(app, event)
            value = app.adductionCheckBox_11.Value;
            app.dataTable{app.nthRow,74} = value;
        end

        % Value changed function: flexionCheckBox_12
        function flexionCheckBox_12ValueChanged(app, event)
            value = app.flexionCheckBox_12.Value;
            app.dataTable{app.nthRow,75} = value;
        end

        % Value changed function: extensionCheckBox_12
        function extensionCheckBox_12ValueChanged(app, event)
            value = app.extensionCheckBox_12.Value;
            app.dataTable{app.nthRow,76} = value;
        end

        % Value changed function: abductionCheckBox_12
        function abductionCheckBox_12ValueChanged(app, event)
            value = app.abductionCheckBox_12.Value;
            app.dataTable{app.nthRow,77} = value;
        end

        % Value changed function: adductionCheckBox_12
        function adductionCheckBox_12ValueChanged(app, event)
            value = app.adductionCheckBox_12.Value;
            app.dataTable{app.nthRow,78} = value;
        end

        % Value changed function: clockwiserotationCheckBox
        function clockwiserotationCheckBoxValueChanged(app, event)
            value = app.clockwiserotationCheckBox.Value;
            app.dataTable{app.nthRow,79} = value;
        end

        % Value changed function: counterclockwiserotationCheckBox
        function counterclockwiserotationCheckBoxValueChanged(app, event)
            value = app.counterclockwiserotationCheckBox.Value;
            app.dataTable{app.nthRow,80} = value;
        end

        % Value changed function: skinhairmovementCheckBox
        function skinhairmovementCheckBoxValueChanged(app, event)
            value = app.skinhairmovementCheckBox.Value;
            app.dataTable{app.nthRow,81} = value;
        end

        % Value changed function: flexionCheckBox_21
        function flexionCheckBox_21ValueChanged(app, event)
            value = app.flexionCheckBox_21.Value;
            app.dataTable{app.nthRow,82} = value;
        end

        % Value changed function: extensionCheckBox_21
        function extensionCheckBox_21ValueChanged(app, event)
            value = app.extensionCheckBox_21.Value;
            app.dataTable{app.nthRow,83} = value;
        end

        % Value changed function: 
        % lateralflexiontocontralateralsideCheckBox
        function lateralflexiontocontralateralsideCheckBoxValueChanged(app, event)
            value = app.lateralflexiontocontralateralsideCheckBox.Value;
            app.dataTable{app.nthRow,84} = value;
        end

        % Value changed function: 
        % lateralflexiontoipsilateralsideCheckBox
        function lateralflexiontoipsilateralsideCheckBoxValueChanged(app, event)
            value = app.lateralflexiontoipsilateralsideCheckBox.Value;
            app.dataTable{app.nthRow,85} = value;
        end

        % Value changed function: flexionCheckBox_13
        function flexionCheckBox_13ValueChanged(app, event)
            value = app.flexionCheckBox_13.Value;
            app.dataTable{app.nthRow,86} = value;
        end

        % Value changed function: extensionCheckBox_13
        function extensionCheckBox_13ValueChanged(app, event)
            value = app.extensionCheckBox_13.Value;
            app.dataTable{app.nthRow,87} = value;
        end

        % Value changed function: medialrotationCheckBox_4
        function medialrotationCheckBox_4ValueChanged(app, event)
            value = app.medialrotationCheckBox_4.Value;
            app.dataTable{app.nthRow,88} = value;
        end

        % Value changed function: lateralrotationCheckBox_4
        function lateralrotationCheckBox_4ValueChanged(app, event)
            value = app.lateralrotationCheckBox_4.Value;
            app.dataTable{app.nthRow,89} = value;
        end

        % Value changed function: abductionCheckBox_7
        function abductionCheckBox_7ValueChanged(app, event)
            value = app.abductionCheckBox_7.Value;
            app.dataTable{app.nthRow,90} = value;
        end

        % Value changed function: adductionCheckBox_7
        function adductionCheckBox_7ValueChanged(app, event)
            value = app.adductionCheckBox_7.Value;
            app.dataTable{app.nthRow,91} = value;
        end

        % Value changed function: flexionCheckBox_17
        function flexionCheckBox_17ValueChanged(app, event)
            value = app.flexionCheckBox_17.Value;
            app.dataTable{app.nthRow,92} = value;
        end

        % Value changed function: extensionCheckBox_17
        function extensionCheckBox_17ValueChanged(app, event)
            value = app.extensionCheckBox_17.Value;
            app.dataTable{app.nthRow,93} = value;
        end

        % Value changed function: medialrotationCheckBox_5
        function medialrotationCheckBox_5ValueChanged(app, event)
            value = app.medialrotationCheckBox_5.Value;
            app.dataTable{app.nthRow,94} = value;
        end

        % Value changed function: lateralrotationCheckBox_5
        function lateralrotationCheckBox_5ValueChanged(app, event)
            value = app.lateralrotationCheckBox_5.Value;
            app.dataTable{app.nthRow,95} = value;
        end

        % Value changed function: abductionCheckBox_9
        function abductionCheckBox_9ValueChanged(app, event)
            value = app.abductionCheckBox_9.Value;
            app.dataTable{app.nthRow,96} = value;
        end

        % Value changed function: adductionCheckBox_9
        function adductionCheckBox_9ValueChanged(app, event)
            value = app.adductionCheckBox_9.Value;
            app.dataTable{app.nthRow,97} = value;
        end

        % Value changed function: flexionCheckBox_14
        function flexionCheckBox_14ValueChanged(app, event)
            value = app.flexionCheckBox_14.Value;
            app.dataTable{app.nthRow,98} = value;
        end

        % Value changed function: extensionCheckBox_14
        function extensionCheckBox_14ValueChanged(app, event)
            value = app.extensionCheckBox_14.Value;
            app.dataTable{app.nthRow,99} = value;
        end

        % Value changed function: pronationCheckBox_3
        function pronationCheckBox_3ValueChanged(app, event)
            value = app.pronationCheckBox_3.Value;
            app.dataTable{app.nthRow,100} = value;
        end

        % Value changed function: supinationCheckBox_3
        function supinationCheckBox_3ValueChanged(app, event)
            value = app.supinationCheckBox_3.Value;
            app.dataTable{app.nthRow,101} = value;
        end

        % Value changed function: flexionCheckBox_18
        function flexionCheckBox_18ValueChanged(app, event)
            value = app.flexionCheckBox_18.Value;
            app.dataTable{app.nthRow,102} = value;
        end

        % Value changed function: extensionCheckBox_18
        function extensionCheckBox_18ValueChanged(app, event)
            value = app.extensionCheckBox_18.Value;
            app.dataTable{app.nthRow,103} = value;
        end

        % Value changed function: pronationCheckBox_4
        function pronationCheckBox_4ValueChanged(app, event)
            value = app.pronationCheckBox_4.Value;
            app.dataTable{app.nthRow,104} = value;
        end

        % Value changed function: supinationCheckBox_4
        function supinationCheckBox_4ValueChanged(app, event)
            value = app.supinationCheckBox_4.Value;
            app.dataTable{app.nthRow,105} = value;
        end

        % Value changed function: flexionCheckBox_15
        function flexionCheckBox_15ValueChanged(app, event)
            value = app.flexionCheckBox_15.Value;
            app.dataTable{app.nthRow,106} = value;
        end

        % Value changed function: extensionCheckBox_15
        function extensionCheckBox_15ValueChanged(app, event)
            value = app.extensionCheckBox_15.Value;
            app.dataTable{app.nthRow,107} = value;
        end

        % Value changed function: inversionCheckBox
        function inversionCheckBoxValueChanged(app, event)
            value = app.inversionCheckBox.Value;
            app.dataTable{app.nthRow,108} = value;
        end

        % Value changed function: eversionCheckBox
        function eversionCheckBoxValueChanged(app, event)
            value = app.eversionCheckBox.Value;
            app.dataTable{app.nthRow,109} = value;
        end

        % Value changed function: flexionCheckBox_19
        function flexionCheckBox_19ValueChanged(app, event)
            value = app.flexionCheckBox_19.Value;
            app.dataTable{app.nthRow,110} = value;
        end

        % Value changed function: extensionCheckBox_19
        function extensionCheckBox_19ValueChanged(app, event)
            value = app.extensionCheckBox_19.Value;
            app.dataTable{app.nthRow,111} = value;
        end

        % Value changed function: inversionCheckBox_2
        function inversionCheckBox_2ValueChanged(app, event)
            value = app.inversionCheckBox_2.Value;
            app.dataTable{app.nthRow,112} = value;
        end

        % Value changed function: eversionCheckBox_2
        function eversionCheckBox_2ValueChanged(app, event)
            value = app.eversionCheckBox_2.Value;
            app.dataTable{app.nthRow,113} = value;
        end

        % Value changed function: flexionCheckBox_16
        function flexionCheckBox_16ValueChanged(app, event)
            value = app.flexionCheckBox_16.Value;
            app.dataTable{app.nthRow,114} = value;
        end

        % Value changed function: extensionCheckBox_16
        function extensionCheckBox_16ValueChanged(app, event)
            value = app.extensionCheckBox_16.Value;
            app.dataTable{app.nthRow,115} = value;
        end

        % Value changed function: abductionCheckBox_8
        function abductionCheckBox_8ValueChanged(app, event)
            value = app.abductionCheckBox_8.Value;
            app.dataTable{app.nthRow,116} = value;
        end

        % Value changed function: adductionCheckBox_8
        function adductionCheckBox_8ValueChanged(app, event)
            value = app.adductionCheckBox_8.Value;
            app.dataTable{app.nthRow,117} = value;
        end

        % Value changed function: flexionCheckBox_20
        function flexionCheckBox_20ValueChanged(app, event)
            value = app.flexionCheckBox_20.Value;
            app.dataTable{app.nthRow,118} = value;
        end

        % Value changed function: extensionCheckBox_20
        function extensionCheckBox_20ValueChanged(app, event)
            value = app.extensionCheckBox_20.Value;
            app.dataTable{app.nthRow,119} = value;
        end

        % Value changed function: abductionCheckBox_10
        function abductionCheckBox_10ValueChanged(app, event)
            value = app.abductionCheckBox_10.Value;
            app.dataTable{app.nthRow,120} = value;
        end

        % Value changed function: adductionCheckBox_10
        function adductionCheckBox_10ValueChanged(app, event)
            value = app.adductionCheckBox_10.Value;
            app.dataTable{app.nthRow,121} = value;
        end

        % Value changed function: yesmovementCheckBox
        function yesmovementCheckBoxValueChanged(app, event)
            value = app.yesmovementCheckBox.Value;
            app.dataTable{app.nthRow,122} = value;
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames,'CheckBox','IgnoreCase',false));
            for i = 1:numel(allCheckBoxNamesList)
                app.(allCheckBoxNamesList{i}).Value = false;
            end
            %new row in excel sheet
            app.nthRow = app.nthRow + 1;
            
            %new site displayed in edit field
            %app.siteEditField.Value = app.siteEditField.Value +1;
            
            %new site and same depth in excel sheet if not specified
            app.dataTable{app.nthRow,1} = app.siteEditField.Value;
            app.dataTable{app.nthRow,2} = app.depthEditField.Value;
            app.dataTable{app.nthRow,4} = app.timeEditField.Value;
            
            %in excel sheet the new amplitude will be the old one times 2
            app.amplitudeEditField.Value = app.amplitudeEditField.Value * 2;
            app.dataTable{app.nthRow,3} = app.amplitudeEditField.Value;
        end

        % Button pushed function: nextsiteButton
        function nextsiteButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames,'CheckBox','IgnoreCase',false));
            for i = 1:numel(allCheckBoxNamesList)
                app.(allCheckBoxNamesList{i}).Value = false;
            end
            
            %new row in excel sheet
            app.nthRow = app.nthRow + 1;
            
            %new site displayed in edit field
            app.siteEditField.Value = app.siteEditField.Value +1;
            app.dataTable{app.nthRow,1} = app.siteEditField.Value;
            
            %set time and depth
            app.dataTable{app.nthRow,2} = app.depthEditField.Value;
            app.dataTable{app.nthRow,4} = app.timeEditField.Value;
            
            %reset amplitude
            app.amplitudeEditField.Value = 20;
            app.dataTable{app.nthRow,3} = app.amplitudeEditField.Value;
        end

        % Button pushed function: doneButton
        function doneButtonPushed(app, event)
            writetable(app.dataTable, "dataTable.xlsx")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.siteEditFieldLabel = uilabel(app.UIFigure);
            app.siteEditFieldLabel.Text = 'site';
            app.siteEditField = uieditfield(app.UIFigure, 'numeric');
            app.siteEditField.ValueChangedFcn = createCallbackFcn(app, @siteEditFieldValueChanged, true);
            app.depthEditFieldLabel = uilabel(app.UIFigure);
            app.depthEditFieldLabel.Text = 'depth';
            app.depthEditField = uieditfield(app.UIFigure, 'numeric');
            app.depthEditField.ValueChangedFcn = createCallbackFcn(app, @depthEditFieldValueChanged, true);
            app.amplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.amplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @amplitudeEditFieldValueChanged, true);
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeEditField.ValueChangedFcn = createCallbackFcn(app, @timeEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.headandfaceTab = uitab(app.TabGroup);
            app.headandfaceTab.Title = 'head and face';
            app.vibrissaeLabel = uilabel(app.headandfaceTab);
            app.vibrissaeLabel.Text = 'vibrissae';
            app.protractionCheckBox_6 = uicheckbox(app.headandfaceTab);
            app.protractionCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @protractionCheckBox_6ValueChanged, true);
            app.protractionCheckBox_6.Text = 'protraction';
            app.retractionCheckBox_6 = uicheckbox(app.headandfaceTab);
            app.retractionCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @retractionCheckBox_6ValueChanged, true);
            app.retractionCheckBox_6.Text = 'retraction';
            app.contralateralLabel_9 = uilabel(app.headandfaceTab);
            app.contralateralLabel_9.Text = 'contralateral';
            app.ipsilateralLabel_9 = uilabel(app.headandfaceTab);
            app.ipsilateralLabel_9.Text = 'ipsilateral';
            app.mystacialpadLabel = uilabel(app.headandfaceTab);
            app.mystacialpadLabel.Text = 'mystacial pad';
            app.protractionCheckBox_7 = uicheckbox(app.headandfaceTab);
            app.protractionCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @protractionCheckBox_7ValueChanged, true);
            app.protractionCheckBox_7.Text = 'protraction';
            app.retractionCheckBox_7 = uicheckbox(app.headandfaceTab);
            app.retractionCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @retractionCheckBox_7ValueChanged, true);
            app.retractionCheckBox_7.Text = 'retraction';
            app.movementCheckBox_3 = uicheckbox(app.headandfaceTab);
            app.movementCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @movementCheckBox_3ValueChanged, true);
            app.movementCheckBox_3.Text = 'movement';
            app.movementCheckBox_4 = uicheckbox(app.headandfaceTab);
            app.movementCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @movementCheckBox_4ValueChanged, true);
            app.movementCheckBox_4.Text = 'movement';
            app.eyeLabel = uilabel(app.headandfaceTab);
            app.eyeLabel.Text = 'eye';
            app.eyemovementCheckBox = uicheckbox(app.headandfaceTab);
            app.eyemovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @eyemovementCheckBoxValueChanged, true);
            app.eyemovementCheckBox.Text = 'eye movement';
            app.eyelidmovementCheckBox = uicheckbox(app.headandfaceTab);
            app.eyelidmovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @eyelidmovementCheckBoxValueChanged, true);
            app.eyelidmovementCheckBox.Text = 'eye-lid movement';
            app.jawLabel = uilabel(app.headandfaceTab);
            app.jawLabel.Text = 'jaw';
            app.protractionCheckBox_8 = uicheckbox(app.headandfaceTab);
            app.protractionCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @protractionCheckBox_8ValueChanged, true);
            app.protractionCheckBox_8.Text = 'protraction';
            app.retractionCheckBox_8 = uicheckbox(app.headandfaceTab);
            app.retractionCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @retractionCheckBox_8ValueChanged, true);
            app.retractionCheckBox_8.Text = 'retraction';
            app.openingCheckBox = uicheckbox(app.headandfaceTab);
            app.openingCheckBox.ValueChangedFcn = createCallbackFcn(app, @openingCheckBoxValueChanged, true);
            app.openingCheckBox.Text = 'opening';
            app.tongueLabel = uilabel(app.headandfaceTab);
            app.tongueLabel.Text = 'tongue';
            app.movementCheckBox = uicheckbox(app.headandfaceTab);
            app.movementCheckBox.ValueChangedFcn = createCallbackFcn(app, @movementCheckBoxValueChanged, true);
            app.movementCheckBox.Text = 'movement';
            app.closingCheckBox = uicheckbox(app.headandfaceTab);
            app.closingCheckBox.ValueChangedFcn = createCallbackFcn(app, @closingCheckBoxValueChanged, true);
            app.closingCheckBox.Text = 'closing';
            app.contralateralLabel_11 = uilabel(app.headandfaceTab);
            app.contralateralLabel_11.Text = 'contralateral';
            app.ipsilateralLabel_11 = uilabel(app.headandfaceTab);
            app.ipsilateralLabel_11.Text = 'ipsilateral';
            app.eyemovementCheckBox_2 = uicheckbox(app.headandfaceTab);
            app.eyemovementCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @eyemovementCheckBox_2ValueChanged, true);
            app.eyemovementCheckBox_2.Text = 'eye movement';
            app.eyelidmovementCheckBox_2 = uicheckbox(app.headandfaceTab);
            app.eyelidmovementCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @eyelidmovementCheckBox_2ValueChanged, true);
            app.eyelidmovementCheckBox_2.Text = 'eye-lid movement';
            app.contralateralLabel_12 = uilabel(app.headandfaceTab);
            app.contralateralLabel_12.Text = 'contralateral';
            app.ipsilateralLabel_12 = uilabel(app.headandfaceTab);
            app.ipsilateralLabel_12.Text = 'ipsilateral';
            app.protractionCheckBox_9 = uicheckbox(app.headandfaceTab);
            app.protractionCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @protractionCheckBox_9ValueChanged, true);
            app.protractionCheckBox_9.Text = 'protraction';
            app.retractionCheckBox_9 = uicheckbox(app.headandfaceTab);
            app.retractionCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @retractionCheckBox_9ValueChanged, true);
            app.retractionCheckBox_9.Text = 'retraction';
            app.openingCheckBox_2 = uicheckbox(app.headandfaceTab);
            app.openingCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @openingCheckBox_2ValueChanged, true);
            app.openingCheckBox_2.Text = 'opening';
            app.closingCheckBox_2 = uicheckbox(app.headandfaceTab);
            app.closingCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @closingCheckBox_2ValueChanged, true);
            app.closingCheckBox_2.Text = 'closing';
            app.contralateralLabel_13 = uilabel(app.headandfaceTab);
            app.contralateralLabel_13.Text = 'contralateral';
            app.ipsilateralLabel_13 = uilabel(app.headandfaceTab);
            app.ipsilateralLabel_13.Text = 'ipsilateral';
            app.movementCheckBox_2 = uicheckbox(app.headandfaceTab);
            app.movementCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @movementCheckBox_2ValueChanged, true);
            app.movementCheckBox_2.Text = 'movement';
            app.contralateralLabel_14 = uilabel(app.headandfaceTab);
            app.contralateralLabel_14.Text = 'contralateral';
            app.ipsilateralLabel_14 = uilabel(app.headandfaceTab);
            app.ipsilateralLabel_14.Text = 'ipsilateral';
            app.vibrationCheckBox = uicheckbox(app.headandfaceTab);
            app.vibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @vibrationCheckBoxValueChanged, true);
            app.vibrationCheckBox.Text = 'vibration';
            app.vibrationCheckBox_2 = uicheckbox(app.headandfaceTab);
            app.vibrationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @vibrationCheckBox_2ValueChanged, true);
            app.vibrationCheckBox_2.Text = 'vibration';
            app.lipLabel = uilabel(app.headandfaceTab);
            app.lipLabel.Text = 'lip';
            app.contralateralLabel_15 = uilabel(app.headandfaceTab);
            app.contralateralLabel_15.Text = 'contralateral';
            app.ipsilateralLabel_15 = uilabel(app.headandfaceTab);
            app.ipsilateralLabel_15.Text = 'ipsilateral';
            app.upperCheckBox = uicheckbox(app.headandfaceTab);
            app.upperCheckBox.ValueChangedFcn = createCallbackFcn(app, @upperCheckBoxValueChanged, true);
            app.upperCheckBox.Text = 'upper';
            app.lowerCheckBox = uicheckbox(app.headandfaceTab);
            app.lowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @lowerCheckBoxValueChanged, true);
            app.lowerCheckBox.Text = 'lower';
            app.upperCheckBox_2 = uicheckbox(app.headandfaceTab);
            app.upperCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @upperCheckBox_2ValueChanged, true);
            app.upperCheckBox_2.Text = 'upper';
            app.lowerCheckBox_2 = uicheckbox(app.headandfaceTab);
            app.lowerCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @lowerCheckBox_2ValueChanged, true);
            app.lowerCheckBox_2.Text = 'lower';
            app.earLabel = uilabel(app.headandfaceTab);
            app.earLabel.Text = 'ear';
            app.movementCheckBox_5 = uicheckbox(app.headandfaceTab);
            app.movementCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @movementCheckBox_5ValueChanged, true);
            app.movementCheckBox_5.Text = 'movement';
            app.movementCheckBox_6 = uicheckbox(app.headandfaceTab);
            app.movementCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @movementCheckBox_6ValueChanged, true);
            app.movementCheckBox_6.Text = 'movement';
            app.contralateralLabel_16 = uilabel(app.headandfaceTab);
            app.contralateralLabel_16.Text = 'contralateral';
            app.ipsilateralLabel_16 = uilabel(app.headandfaceTab);
            app.ipsilateralLabel_16.Text = 'ipsilateral';
            app.neckLabel = uilabel(app.headandfaceTab);
            app.neckLabel.Text = 'neck';
            app.movementCheckBox_7 = uicheckbox(app.headandfaceTab);
            app.movementCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @movementCheckBox_7ValueChanged, true);
            app.movementCheckBox_7.Text = 'movement';
            app.movementCheckBox_8 = uicheckbox(app.headandfaceTab);
            app.movementCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @movementCheckBox_8ValueChanged, true);
            app.movementCheckBox_8.Text = 'movement';
            app.contralateralLabel_17 = uilabel(app.headandfaceTab);
            app.contralateralLabel_17.Text = 'contralateral';
            app.ipsilateralLabel_17 = uilabel(app.headandfaceTab);
            app.ipsilateralLabel_17.Text = 'ipsilateral';
            app.forelimbTab = uitab(app.TabGroup);
            app.forelimbTab.Title = 'forelimb';
            app.shoulderLabel = uilabel(app.forelimbTab);
            app.shoulderLabel.Text = 'shoulder';
            app.elbowLabel = uilabel(app.forelimbTab);
            app.elbowLabel.Text = 'elbow';
            app.wristLabel = uilabel(app.forelimbTab);
            app.wristLabel.Text = 'wrist';
            app.digitsLabel = uilabel(app.forelimbTab);
            app.digitsLabel.Text = 'digits';
            app.flexionCheckBox = uicheckbox(app.forelimbTab);
            app.flexionCheckBox.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBoxValueChanged, true);
            app.flexionCheckBox.Text = 'flexion';
            app.extensionCheckBox = uicheckbox(app.forelimbTab);
            app.extensionCheckBox.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBoxValueChanged, true);
            app.extensionCheckBox.Text = 'extension';
            app.medialrotationCheckBox = uicheckbox(app.forelimbTab);
            app.medialrotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @medialrotationCheckBoxValueChanged, true);
            app.medialrotationCheckBox.Text = 'medial rotation';
            app.lateralrotationCheckBox = uicheckbox(app.forelimbTab);
            app.lateralrotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @lateralrotationCheckBoxValueChanged, true);
            app.lateralrotationCheckBox.Text = 'lateral rotation';
            app.flexionCheckBox_2 = uicheckbox(app.forelimbTab);
            app.flexionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_2ValueChanged, true);
            app.flexionCheckBox_2.Text = 'flexion';
            app.extensionCheckBox_2 = uicheckbox(app.forelimbTab);
            app.extensionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_2ValueChanged, true);
            app.extensionCheckBox_2.Text = 'extension';
            app.pronationCheckBox = uicheckbox(app.forelimbTab);
            app.pronationCheckBox.ValueChangedFcn = createCallbackFcn(app, @pronationCheckBoxValueChanged, true);
            app.pronationCheckBox.Text = 'pronation';
            app.supinationCheckBox = uicheckbox(app.forelimbTab);
            app.supinationCheckBox.ValueChangedFcn = createCallbackFcn(app, @supinationCheckBoxValueChanged, true);
            app.supinationCheckBox.Text = 'supination';
            app.flexionCheckBox_3 = uicheckbox(app.forelimbTab);
            app.flexionCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_3ValueChanged, true);
            app.flexionCheckBox_3.Text = 'flexion';
            app.extensionCheckBox_3 = uicheckbox(app.forelimbTab);
            app.extensionCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_3ValueChanged, true);
            app.extensionCheckBox_3.Text = 'extension';
            app.flexionCheckBox_4 = uicheckbox(app.forelimbTab);
            app.flexionCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_4ValueChanged, true);
            app.flexionCheckBox_4.Text = 'flexion';
            app.extensionCheckBox_4 = uicheckbox(app.forelimbTab);
            app.extensionCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_4ValueChanged, true);
            app.extensionCheckBox_4.Text = 'extension';
            app.protractionCheckBox = uicheckbox(app.forelimbTab);
            app.protractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @protractionCheckBoxValueChanged, true);
            app.protractionCheckBox.Text = 'protraction';
            app.retractionCheckBox = uicheckbox(app.forelimbTab);
            app.retractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @retractionCheckBoxValueChanged, true);
            app.retractionCheckBox.Text = 'retraction';
            app.abductionCheckBox = uicheckbox(app.forelimbTab);
            app.abductionCheckBox.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBoxValueChanged, true);
            app.abductionCheckBox.Text = 'abduction';
            app.adductionCheckBox = uicheckbox(app.forelimbTab);
            app.adductionCheckBox.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBoxValueChanged, true);
            app.adductionCheckBox.Text = 'adduction';
            app.abductionCheckBox_2 = uicheckbox(app.forelimbTab);
            app.abductionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_2ValueChanged, true);
            app.abductionCheckBox_2.Text = 'abduction';
            app.adductionCheckBox_2 = uicheckbox(app.forelimbTab);
            app.adductionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_2ValueChanged, true);
            app.adductionCheckBox_2.Text = 'adduction';
            app.contralateralLabel = uilabel(app.forelimbTab);
            app.contralateralLabel.Text = 'contralateral';
            app.ipsilateralLabel = uilabel(app.forelimbTab);
            app.ipsilateralLabel.Text = 'ipsilateral';
            app.flexionCheckBox_9 = uicheckbox(app.forelimbTab);
            app.flexionCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_9ValueChanged, true);
            app.flexionCheckBox_9.Text = 'flexion';
            app.extensionCheckBox_9 = uicheckbox(app.forelimbTab);
            app.extensionCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_9ValueChanged, true);
            app.extensionCheckBox_9.Text = 'extension';
            app.medialrotationCheckBox_3 = uicheckbox(app.forelimbTab);
            app.medialrotationCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @medialrotationCheckBox_3ValueChanged, true);
            app.medialrotationCheckBox_3.Text = 'medial rotation';
            app.lateralrotationCheckBox_3 = uicheckbox(app.forelimbTab);
            app.lateralrotationCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @lateralrotationCheckBox_3ValueChanged, true);
            app.lateralrotationCheckBox_3.Text = 'lateral rotation';
            app.protractionCheckBox_3 = uicheckbox(app.forelimbTab);
            app.protractionCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @protractionCheckBox_3ValueChanged, true);
            app.protractionCheckBox_3.Text = 'protraction';
            app.retractionCheckBox_3 = uicheckbox(app.forelimbTab);
            app.retractionCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @retractionCheckBox_3ValueChanged, true);
            app.retractionCheckBox_3.Text = 'retraction';
            app.abductionCheckBox_5 = uicheckbox(app.forelimbTab);
            app.abductionCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_5ValueChanged, true);
            app.abductionCheckBox_5.Text = 'abduction';
            app.adductionCheckBox_5 = uicheckbox(app.forelimbTab);
            app.adductionCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_5ValueChanged, true);
            app.adductionCheckBox_5.Text = 'adduction';
            app.contralateralLabel_2 = uilabel(app.forelimbTab);
            app.contralateralLabel_2.Text = 'contralateral';
            app.ipsilateralLabel_2 = uilabel(app.forelimbTab);
            app.ipsilateralLabel_2.Text = 'ipsilateral';
            app.flexionCheckBox_10 = uicheckbox(app.forelimbTab);
            app.flexionCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_10ValueChanged, true);
            app.flexionCheckBox_10.Text = 'flexion';
            app.extensionCheckBox_10 = uicheckbox(app.forelimbTab);
            app.extensionCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_10ValueChanged, true);
            app.extensionCheckBox_10.Text = 'extension';
            app.pronationCheckBox_2 = uicheckbox(app.forelimbTab);
            app.pronationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @pronationCheckBox_2ValueChanged, true);
            app.pronationCheckBox_2.Text = 'pronation';
            app.supinationCheckBox_2 = uicheckbox(app.forelimbTab);
            app.supinationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @supinationCheckBox_2ValueChanged, true);
            app.supinationCheckBox_2.Text = 'supination';
            app.contralateralLabel_3 = uilabel(app.forelimbTab);
            app.contralateralLabel_3.Text = 'contralateral';
            app.ipsilateralLabel_3 = uilabel(app.forelimbTab);
            app.ipsilateralLabel_3.Text = 'ipsilateral';
            app.flexionCheckBox_11 = uicheckbox(app.forelimbTab);
            app.flexionCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_11ValueChanged, true);
            app.flexionCheckBox_11.Text = 'flexion';
            app.extensionCheckBox_11 = uicheckbox(app.forelimbTab);
            app.extensionCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_11ValueChanged, true);
            app.extensionCheckBox_11.Text = 'extension';
            app.abductionCheckBox_6 = uicheckbox(app.forelimbTab);
            app.abductionCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_6ValueChanged, true);
            app.abductionCheckBox_6.Text = 'abduction';
            app.adductionCheckBox_6 = uicheckbox(app.forelimbTab);
            app.adductionCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_6ValueChanged, true);
            app.adductionCheckBox_6.Text = 'adduction';
            app.contralateralLabel_4 = uilabel(app.forelimbTab);
            app.contralateralLabel_4.Text = 'contralateral';
            app.ipsilateralLabel_4 = uilabel(app.forelimbTab);
            app.ipsilateralLabel_4.Text = 'ipsilateral';
            app.flexionCheckBox_12 = uicheckbox(app.forelimbTab);
            app.flexionCheckBox_12.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_12ValueChanged, true);
            app.flexionCheckBox_12.Text = 'flexion';
            app.extensionCheckBox_12 = uicheckbox(app.forelimbTab);
            app.extensionCheckBox_12.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_12ValueChanged, true);
            app.extensionCheckBox_12.Text = 'extension';
            app.forearmLabel = uilabel(app.forelimbTab);
            app.forearmLabel.Text = 'forearm';
            app.contralateralLabel_10 = uilabel(app.forelimbTab);
            app.contralateralLabel_10.Text = 'contralateral';
            app.ipsilateralLabel_10 = uilabel(app.forelimbTab);
            app.ipsilateralLabel_10.Text = 'ipsilateral';
            app.abductionCheckBox_11 = uicheckbox(app.forelimbTab);
            app.abductionCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_11ValueChanged, true);
            app.abductionCheckBox_11.Text = 'abduction';
            app.adductionCheckBox_11 = uicheckbox(app.forelimbTab);
            app.adductionCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_11ValueChanged, true);
            app.adductionCheckBox_11.Text = 'adduction';
            app.abductionCheckBox_12 = uicheckbox(app.forelimbTab);
            app.abductionCheckBox_12.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_12ValueChanged, true);
            app.abductionCheckBox_12.Text = 'abduction';
            app.adductionCheckBox_12 = uicheckbox(app.forelimbTab);
            app.adductionCheckBox_12.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_12ValueChanged, true);
            app.adductionCheckBox_12.Text = 'adduction';
            app.elevationCheckBox = uicheckbox(app.forelimbTab);
            app.elevationCheckBox.ValueChangedFcn = createCallbackFcn(app, @elevationCheckBoxValueChanged, true);
            app.elevationCheckBox.Text = 'elevation';
            app.depressionCheckBox = uicheckbox(app.forelimbTab);
            app.depressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @depressionCheckBoxValueChanged, true);
            app.depressionCheckBox.Text = 'depression';
            app.elevationCheckBox_2 = uicheckbox(app.forelimbTab);
            app.elevationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @elevationCheckBox_2ValueChanged, true);
            app.elevationCheckBox_2.Text = 'elevation';
            app.depressionCheckBox_2 = uicheckbox(app.forelimbTab);
            app.depressionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @depressionCheckBox_2ValueChanged, true);
            app.depressionCheckBox_2.Text = 'depression';
            app.trunkTab = uitab(app.TabGroup);
            app.trunkTab.Title = 'trunk';
            app.clockwiserotationCheckBox = uicheckbox(app.trunkTab);
            app.clockwiserotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @clockwiserotationCheckBoxValueChanged, true);
            app.clockwiserotationCheckBox.Text = 'clockwise rotation';
            app.skinhairmovementCheckBox = uicheckbox(app.trunkTab);
            app.skinhairmovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @skinhairmovementCheckBoxValueChanged, true);
            app.skinhairmovementCheckBox.Text = 'skin/ hair movement';
            app.flexionCheckBox_21 = uicheckbox(app.trunkTab);
            app.flexionCheckBox_21.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_21ValueChanged, true);
            app.flexionCheckBox_21.Text = 'flexion';
            app.extensionCheckBox_21 = uicheckbox(app.trunkTab);
            app.extensionCheckBox_21.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_21ValueChanged, true);
            app.extensionCheckBox_21.Text = 'extension';
            app.lateralflexiontocontralateralsideCheckBox = uicheckbox(app.trunkTab);
            app.lateralflexiontocontralateralsideCheckBox.ValueChangedFcn = createCallbackFcn(app, @lateralflexiontocontralateralsideCheckBoxValueChanged, true);
            app.lateralflexiontocontralateralsideCheckBox.Text = 'lateral flexion (to contralateral side)';
            app.lateralflexiontoipsilateralsideCheckBox = uicheckbox(app.trunkTab);
            app.lateralflexiontoipsilateralsideCheckBox.ValueChangedFcn = createCallbackFcn(app, @lateralflexiontoipsilateralsideCheckBoxValueChanged, true);
            app.lateralflexiontoipsilateralsideCheckBox.Text = 'lateral flexion (to ipsilateral side)';
            app.counterclockwiserotationCheckBox = uicheckbox(app.trunkTab);
            app.counterclockwiserotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @counterclockwiserotationCheckBoxValueChanged, true);
            app.counterclockwiserotationCheckBox.Text = 'counter clockwise rotation';
            app.hindlimbTab = uitab(app.TabGroup);
            app.hindlimbTab.Title = 'hindlimb';
            app.hipLabel = uilabel(app.hindlimbTab);
            app.hipLabel.Text = 'hip';
            app.kneeLabel = uilabel(app.hindlimbTab);
            app.kneeLabel.Text = 'knee';
            app.ankleLabel = uilabel(app.hindlimbTab);
            app.ankleLabel.Text = 'ankle';
            app.toesLabel = uilabel(app.hindlimbTab);
            app.toesLabel.Text = 'toes';
            app.flexionCheckBox_13 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_13.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_13ValueChanged, true);
            app.flexionCheckBox_13.Text = 'flexion';
            app.extensionCheckBox_13 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_13.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_13ValueChanged, true);
            app.extensionCheckBox_13.Text = 'extension';
            app.medialrotationCheckBox_4 = uicheckbox(app.hindlimbTab);
            app.medialrotationCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @medialrotationCheckBox_4ValueChanged, true);
            app.medialrotationCheckBox_4.Text = 'medial rotation';
            app.lateralrotationCheckBox_4 = uicheckbox(app.hindlimbTab);
            app.lateralrotationCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @lateralrotationCheckBox_4ValueChanged, true);
            app.lateralrotationCheckBox_4.Text = 'lateral rotation';
            app.flexionCheckBox_14 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_14.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_14ValueChanged, true);
            app.flexionCheckBox_14.Text = 'flexion';
            app.extensionCheckBox_14 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_14.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_14ValueChanged, true);
            app.extensionCheckBox_14.Text = 'extension';
            app.pronationCheckBox_3 = uicheckbox(app.hindlimbTab);
            app.pronationCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @pronationCheckBox_3ValueChanged, true);
            app.pronationCheckBox_3.Text = 'pronation';
            app.supinationCheckBox_3 = uicheckbox(app.hindlimbTab);
            app.supinationCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @supinationCheckBox_3ValueChanged, true);
            app.supinationCheckBox_3.Text = 'supination';
            app.flexionCheckBox_15 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_15.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_15ValueChanged, true);
            app.flexionCheckBox_15.Text = 'flexion';
            app.extensionCheckBox_15 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_15.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_15ValueChanged, true);
            app.extensionCheckBox_15.Text = 'extension';
            app.flexionCheckBox_16 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_16.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_16ValueChanged, true);
            app.flexionCheckBox_16.Text = 'flexion';
            app.extensionCheckBox_16 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_16.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_16ValueChanged, true);
            app.extensionCheckBox_16.Text = 'extension';
            app.abductionCheckBox_7 = uicheckbox(app.hindlimbTab);
            app.abductionCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_7ValueChanged, true);
            app.abductionCheckBox_7.Text = 'abduction';
            app.adductionCheckBox_7 = uicheckbox(app.hindlimbTab);
            app.adductionCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_7ValueChanged, true);
            app.adductionCheckBox_7.Text = 'adduction';
            app.abductionCheckBox_8 = uicheckbox(app.hindlimbTab);
            app.abductionCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_8ValueChanged, true);
            app.abductionCheckBox_8.Text = 'abduction';
            app.adductionCheckBox_8 = uicheckbox(app.hindlimbTab);
            app.adductionCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_8ValueChanged, true);
            app.adductionCheckBox_8.Text = 'adduction';
            app.contralateralLabel_5 = uilabel(app.hindlimbTab);
            app.contralateralLabel_5.Text = 'contralateral';
            app.ipsilateralLabel_5 = uilabel(app.hindlimbTab);
            app.ipsilateralLabel_5.Text = 'ipsilateral';
            app.flexionCheckBox_17 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_17.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_17ValueChanged, true);
            app.flexionCheckBox_17.Text = 'flexion';
            app.extensionCheckBox_17 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_17.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_17ValueChanged, true);
            app.extensionCheckBox_17.Text = 'extension';
            app.medialrotationCheckBox_5 = uicheckbox(app.hindlimbTab);
            app.medialrotationCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @medialrotationCheckBox_5ValueChanged, true);
            app.medialrotationCheckBox_5.Text = 'medial rotation';
            app.lateralrotationCheckBox_5 = uicheckbox(app.hindlimbTab);
            app.lateralrotationCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @lateralrotationCheckBox_5ValueChanged, true);
            app.lateralrotationCheckBox_5.Text = 'lateral rotation';
            app.abductionCheckBox_9 = uicheckbox(app.hindlimbTab);
            app.abductionCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_9ValueChanged, true);
            app.abductionCheckBox_9.Text = 'abduction';
            app.adductionCheckBox_9 = uicheckbox(app.hindlimbTab);
            app.adductionCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_9ValueChanged, true);
            app.adductionCheckBox_9.Text = 'adduction';
            app.contralateralLabel_6 = uilabel(app.hindlimbTab);
            app.contralateralLabel_6.Text = 'contralateral';
            app.ipsilateralLabel_6 = uilabel(app.hindlimbTab);
            app.ipsilateralLabel_6.Text = 'ipsilateral';
            app.flexionCheckBox_18 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_18.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_18ValueChanged, true);
            app.flexionCheckBox_18.Text = 'flexion';
            app.extensionCheckBox_18 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_18.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_18ValueChanged, true);
            app.extensionCheckBox_18.Text = 'extension';
            app.pronationCheckBox_4 = uicheckbox(app.hindlimbTab);
            app.pronationCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @pronationCheckBox_4ValueChanged, true);
            app.pronationCheckBox_4.Text = 'pronation';
            app.supinationCheckBox_4 = uicheckbox(app.hindlimbTab);
            app.supinationCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @supinationCheckBox_4ValueChanged, true);
            app.supinationCheckBox_4.Text = 'supination';
            app.contralateralLabel_7 = uilabel(app.hindlimbTab);
            app.contralateralLabel_7.Text = 'contralateral';
            app.ipsilateralLabel_7 = uilabel(app.hindlimbTab);
            app.ipsilateralLabel_7.Text = 'ipsilateral';
            app.flexionCheckBox_19 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_19.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_19ValueChanged, true);
            app.flexionCheckBox_19.Text = 'flexion';
            app.extensionCheckBox_19 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_19.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_19ValueChanged, true);
            app.extensionCheckBox_19.Text = 'extension';
            app.abductionCheckBox_10 = uicheckbox(app.hindlimbTab);
            app.abductionCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @abductionCheckBox_10ValueChanged, true);
            app.abductionCheckBox_10.Text = 'abduction';
            app.adductionCheckBox_10 = uicheckbox(app.hindlimbTab);
            app.adductionCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @adductionCheckBox_10ValueChanged, true);
            app.adductionCheckBox_10.Text = 'adduction';
            app.contralateralLabel_8 = uilabel(app.hindlimbTab);
            app.contralateralLabel_8.Text = 'contralateral';
            app.ipsilateralLabel_8 = uilabel(app.hindlimbTab);
            app.ipsilateralLabel_8.Text = 'ipsilateral';
            app.flexionCheckBox_20 = uicheckbox(app.hindlimbTab);
            app.flexionCheckBox_20.ValueChangedFcn = createCallbackFcn(app, @flexionCheckBox_20ValueChanged, true);
            app.flexionCheckBox_20.Text = 'flexion';
            app.extensionCheckBox_20 = uicheckbox(app.hindlimbTab);
            app.extensionCheckBox_20.ValueChangedFcn = createCallbackFcn(app, @extensionCheckBox_20ValueChanged, true);
            app.extensionCheckBox_20.Text = 'extension';
            app.inversionCheckBox = uicheckbox(app.hindlimbTab);
            app.inversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @inversionCheckBoxValueChanged, true);
            app.inversionCheckBox.Text = 'inversion';
            app.eversionCheckBox = uicheckbox(app.hindlimbTab);
            app.eversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @eversionCheckBoxValueChanged, true);
            app.eversionCheckBox.Text = 'eversion';
            app.inversionCheckBox_2 = uicheckbox(app.hindlimbTab);
            app.inversionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @inversionCheckBox_2ValueChanged, true);
            app.inversionCheckBox_2.Text = 'inversion';
            app.eversionCheckBox_2 = uicheckbox(app.hindlimbTab);
            app.eversionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @eversionCheckBox_2ValueChanged, true);
            app.eversionCheckBox_2.Text = 'eversion';
            app.tailTab = uitab(app.TabGroup);
            app.tailTab.Title = 'tail';
            app.yesmovementCheckBox = uicheckbox(app.tailTab);
            app.yesmovementCheckBox.ValueChangedFcn = createCallbackFcn(app, @yesmovementCheckBoxValueChanged, true);
            app.yesmovementCheckBox.Text = 'yes movement';
            app.othersTab = uitab(app.TabGroup);
            app.othersTab.Title = 'others';
            app.extraEditFieldLabel = uilabel(app.othersTab);
            app.extraEditFieldLabel.Text = 'extra';
            app.extraEditField = uieditfield(app.othersTab, 'text');
            app.pleasetypeinanyextrainformationhereLabel = uilabel(app.othersTab);
            app.pleasetypeinanyextrainformationhereLabel.Text = 'please type in any extra information here: ';
            app.doneButton = uibutton(app.UIFigure, 'push');
            app.doneButton.ButtonPushedFcn = createCallbackFcn(app, @doneButtonPushed, true);
            app.doneButton.Text = 'done';
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.nextsiteButton = uibutton(app.UIFigure, 'push');
            app.nextsiteButton.ButtonPushedFcn = createCallbackFcn(app, @nextsiteButtonPushed, true);
            app.nextsiteButton.Text = 'next site';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = movementEntryMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end